classdef PipelineConfig_dlgBox < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PipelineConfigurationLabel      matlab.ui.control.Label
        AvailablefunctionsListBoxLabel  matlab.ui.control.Label
        AvailablefunctionsListBox       matlab.ui.control.ListBox
        SelectedfunctionsListBoxLabel   matlab.ui.control.Label
        SelectedfunctionsListBox        matlab.ui.control.ListBox
        Button_2                        matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        Button_3                        matlab.ui.control.Button
        Button_4                        matlab.ui.control.Button
        CreatePipelineButton            matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        SetOptionsButton                matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        DeleteintermediatefilesCheckBox  matlab.ui.control.CheckBox
        IgnoreLogBookCheckBox           matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        CallingApp; % Main App
        IdxList % List of indexes from Protocol queryFilter.
        Pipe % Instantiation of PipelineManager class.
    end
    
    methods (Access = private)
        function createFuncList(app)
            Items = {};
            ItemsData = [];
            names = fieldnames(app.Pipe.FunctionList);
            for i = 1:length(names)
                funcNames = fieldnames(app.Pipe.FunctionList.(names{i}));
                for j = 1:length(funcNames)
                    ItemData.className = names{i};
                    ItemData.funcName = funcNames{j};
                    ItemData.info = app.Pipe.FunctionList.(names{i}).(funcNames{j});
                    if ~isempty(ItemData(i).info.opts)
                        funcNames{j} = [funcNames{j} '(!)'];
                    end
                    ItemsData = [ItemsData; ItemData];%#ok
                    Items = [Items; funcNames{j}]; %#ok
                end
            end
            app.AvailablefunctionsListBox.Items = Items;
            app.AvailablefunctionsListBox.ItemsData = ItemsData;
            
            app.SelectedfunctionsListBox.Items = {};
            app.SelectedfunctionsListBox.ItemsData = [];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, IdxList)
            app.CallingApp = mainapp;
            app.IdxList = IdxList;
            app.Pipe = PipelineManager([],app.CallingApp.ProtocolObj);
            % Setup List of Available Functions:
            app.createFuncList;
            
            % ToolTips of buttons
            app.Button.Tooltip = 'Adds function to Pipeline.';
            app.Button_2.Tooltip = 'Removes function from Pipeline.';
            app.Button_3.Tooltip = 'Moves function up.';
            app.Button_4.Tooltip = 'Moves function down.';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.ConfigureNewPipelineButton.Enable = 'on';
            delete(app)
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            ItemData_selected = app.AvailablefunctionsListBox.Value;
            if~isempty(ItemData_selected)
                idx = strcmp({app.AvailablefunctionsListBox.ItemsData.funcName}, app.AvailablefunctionsListBox.Value.funcName);
                Item_selected = app.AvailablefunctionsListBox.Items(idx);
                ItemList = app.SelectedfunctionsListBox.Items;
                if ~ismember(Item_selected, ItemList)
                    app.SelectedfunctionsListBox.Items = [app.SelectedfunctionsListBox.Items Item_selected];
                    app.SelectedfunctionsListBox.ItemsData = [app.SelectedfunctionsListBox.ItemsData ItemData_selected];
                    app.AvailablefunctionsListBox.Items(idx) = [];
                    app.AvailablefunctionsListBox.ItemsData(idx) = [];
                end
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            ItemData_selected = app.SelectedfunctionsListBox.Value;
            if~isempty(ItemData_selected)
                idx = strcmp({app.SelectedfunctionsListBox.ItemsData.funcName}, app.SelectedfunctionsListBox.Value.funcName);
                Item_selected = app.SelectedfunctionsListBox.Items(idx);
                ItemList = app.AvailablefunctionsListBox.Items;
                if ~ismember(Item_selected, ItemList)
                    app.AvailablefunctionsListBox.Items = [app.AvailablefunctionsListBox.Items Item_selected];
                    app.AvailablefunctionsListBox.ItemsData = [app.AvailablefunctionsListBox.ItemsData ItemData_selected];
                    app.SelectedfunctionsListBox.Items(idx) = [];
                    app.SelectedfunctionsListBox.ItemsData(idx) = [];
                end
            end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            ItemData_selected = app.SelectedfunctionsListBox.Value;
            if~isempty(ItemData_selected)
                Items = app.SelectedfunctionsListBox.Items;
                ItemsData = app.SelectedfunctionsListBox.ItemsData;
                idx = strcmp({app.SelectedfunctionsListBox.ItemsData.funcName}, app.SelectedfunctionsListBox.Value.funcName);
                pos = find(idx);
                if pos > 1
                    range = 1:numel(Items);
                    range(pos) = pos -1 ;
                    range(pos -1) = pos;
                    app.SelectedfunctionsListBox.Items = Items(range);
                    app.SelectedfunctionsListBox.ItemsData = ItemsData(range);
                end
            end
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            ItemData_selected = app.SelectedfunctionsListBox.Value;
            if~isempty(ItemData_selected)
                Items = app.SelectedfunctionsListBox.Items;
                ItemsData = app.SelectedfunctionsListBox.ItemsData;
                idx = strcmp({app.SelectedfunctionsListBox.ItemsData.funcName}, app.SelectedfunctionsListBox.Value.funcName);
                pos = find(idx);
                if pos < numel(Items)
                    range = 1:numel(Items);
                    range(pos) = pos + 1;
                    range(pos + 1) = pos;
                    app.SelectedfunctionsListBox.Items = Items(range);
                    app.SelectedfunctionsListBox.ItemsData = ItemsData(range);
                end
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.createFuncList
        end

        % Button pushed function: SetOptionsButton
        function SetOptionsButtonPushed(app, event)
            ItemData_selected = app.SelectedfunctionsListBox.Value;
            if ~isempty(ItemData_selected) && ~isempty(ItemData_selected.info.opts)
                opts = app.Pipe.setOpts(ItemData_selected.className, ItemData_selected.funcName);
                idx = strcmp({app.SelectedfunctionsListBox.ItemsData.funcName}, app.SelectedfunctionsListBox.Value.funcName);
                app.SelectedfunctionsListBox.ItemsData(idx).info.opts = opts;
                if ~isequaln(opts, ItemData_selected.info.opts)
                    app.SelectedfunctionsListBox.Items(idx) = strrep(app.SelectedfunctionsListBox.Items(idx), '(!)', '(custom opts!)');
                end
            end
        end

        % Button pushed function: CreatePipelineButton
        function CreatePipelineButtonPushed(app, event)
            if isempty(app.SelectedfunctionsListBox.Items)
                uialert(app.UIFigure, 'You have to select at least one function to create a Pipeline!', 'Warning!', 'Modal',true);
            end
            ItemsData = app.SelectedfunctionsListBox.ItemsData;
            for i = 1:numel(ItemsData)
                className = ItemsData(i).className;
                funcName = ItemsData(i).funcName;
                if isempty(ItemsData(i).info.opts)
                    app.Pipe.addTask(className, funcName);
                else
                    app.Pipe.addTask(className, funcName, 'opts', ItemsData(i).info.opts);
                end
            end
            app.CallingApp.PipelineObj = app.Pipe;
            app.CallingApp.ConfigureNewPipelineButton.Enable = 'on';
            app.CallingApp.displayPipeInfo(true);
            delete(app)
        end

        % Value changed function: DeleteintermediatefilesCheckBox
        function DeleteintermediatefilesCheckBoxValueChanged(app, event)
            value = app.DeleteintermediatefilesCheckBox.Value;
            if value
                app.Pipe.EraseIntermediate = true;
            end
        end

        % Value changed function: IgnoreLogBookCheckBox
        function IgnoreLogBookCheckBoxValueChanged(app, event)
            value = app.IgnoreLogBookCheckBox.Value;
            if value
                app.Pipe.IgnoreLogBook = true;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.PipelineConfigurationLabel = uilabel(app.UIFigure);
            app.PipelineConfigurationLabel.Text = 'Pipeline Configuration';
            app.AvailablefunctionsListBoxLabel = uilabel(app.UIFigure);
            app.AvailablefunctionsListBoxLabel.Text = 'Available functions:';
            app.AvailablefunctionsListBox = uilistbox(app.UIFigure);
            app.AvailablefunctionsListBox.Items = {};
            app.AvailablefunctionsListBox.Value = {};
            app.SelectedfunctionsListBoxLabel = uilabel(app.UIFigure);
            app.SelectedfunctionsListBoxLabel.Text = 'Selected functions:';
            app.SelectedfunctionsListBox = uilistbox(app.UIFigure);
            app.SelectedfunctionsListBox.Items = {};
            app.SelectedfunctionsListBox.Value = {};
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'icon_arrow_rgt.png';
            app.Button_2.Text = '';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'icon_arrow_lft.png';
            app.Button.Text = '';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = 'icon_arrow_up.png';
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Icon = 'icon_arrow_dwn.png';
            app.Button_4.Text = '';
            app.CreatePipelineButton = uibutton(app.UIFigure, 'push');
            app.CreatePipelineButton.ButtonPushedFcn = createCallbackFcn(app, @CreatePipelineButtonPushed, true);
            app.CreatePipelineButton.Text = 'Create Pipeline';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SetOptionsButton = uibutton(app.UIFigure, 'push');
            app.SetOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @SetOptionsButtonPushed, true);
            app.SetOptionsButton.Text = 'Set Options';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'* items with (!) have optional parameters.'; 'Click in "Set Options" to change them.'};
            app.DeleteintermediatefilesCheckBox = uicheckbox(app.UIFigure);
            app.DeleteintermediatefilesCheckBox.ValueChangedFcn = createCallbackFcn(app, @DeleteintermediatefilesCheckBoxValueChanged, true);
            app.DeleteintermediatefilesCheckBox.Text = 'Delete intermediate files';
            app.IgnoreLogBookCheckBox = uicheckbox(app.UIFigure);
            app.IgnoreLogBookCheckBox.ValueChangedFcn = createCallbackFcn(app, @IgnoreLogBookCheckBoxValueChanged, true);
            app.IgnoreLogBookCheckBox.Text = 'Ignore Log Book';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PipelineConfig_dlgBox(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end