classdef PlotLongData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        OutputMenu                    matlab.ui.container.Menu
        SaveasMenu                    matlab.ui.container.Menu
        ExportasPNGMenu               matlab.ui.container.Menu
        SettingsMenu                  matlab.ui.container.Menu
        AxissettingsMenu              matlab.ui.container.Menu
        ColorMenu                     matlab.ui.container.Menu
        ROIinmainfigureDropDownLabel  matlab.ui.control.Label
        ROIinmainfigureDropDown       matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        statsObj % StatManager object containing ROI data.
        mainFig % Handle to the main figure.
        summaryFigs % Handle to Summary figures.
        axHandleList % List of axis handles from summaryFig.
        mainAx % Handle to axis in main figure;
        scatData = struct('handle', [], 'metaData',{})% Array containing the metaData of each item from the scatter plots in the summary figures.
    end
    
    methods (Access = private)
                
        function createSummaryFigs(app)
            % This method creates figures with line + scatter plots. A maximum of 8 plots
            % is allowed per figure.
            
            
            % Create first figure:
            app.summaryFigs(1) = figure('Name', ['Summary figure #' num2str(1)], 'NumberTitle', 'off',...
                'Position',[0 0 600 700],'Visible','off', 'CloseRequestFcn',@app.DoNothing);
            movegui(app.summaryFigs(1), 'northwest');
            cnt = 1; % Axes counter.
            app.scatData = []
            % Create a plot for each observation:
            for ii = 1:numel(app.statsObj.obs_list)
                % Loop over the number of groups:
                gList = unique(app.statsObj.stats_data(:,1)); 
                % Create axis:
                s = subplot(4,2,cnt,"replace",'Parent',app.summaryFigs(end), 'Tag', app.statsObj.obs_list{ii});
                hold(s,"on")
                scatHandles = [];
                metaData_list = {};
                for j = 1:numel(gList)
                    disp(['Creating plot for obs ' app.statsObj.obs_list{ii} ' and group ' gList{j} '...']);
                    [acqIndx,data, metaData] = app.getData(gList{j}, app.statsObj.obs_list{ii});                   
                    % Create scatter plot:
                    scat = scatter(s,acqIndx,data, 'Tag',['scatG' num2str(j)]); 
                    scatHandles = vertcat(scatHandles, scat);                   
                    metaData_list = cat(3,metaData_list, metaData);
                    % Create errorbar plot (default):
                    % Calculate average and STD of data by acqIndx and create errorbar plot:
                    avg = arrayfun(@(x) mean(data(acqIndx == x), 'omitnan'),unique(acqIndx));
                    stDev = arrayfun(@(x) std(data(acqIndx == x),0,"all", 'omitnan'),unique(acqIndx));
                    errorbar(s,unique(acqIndx),avg,stDev, 'Tag', ['errG' num2str(j)]);                                         
                    % 
                end
                app.scatData(ii).handles = scatHandles;
                app.scatData(ii).metaData = metaData_list;
                title(s,app.statsObj.obs_list{ii}, 'Interpreter',"none");
                hold(s,"off")
                cnt = cnt+1;
                if cnt == 8
                    cnt = 1;
                    if ii<numel(app.statsObj.obs_list)
                        app.summaryFigs = vertcat(app.summaryFigs,...
                            figure('Name', ['Summary figure #' num2str(length(app.summaryFigs)+1)], 'NumberTitle', 'off',...
                            'Position',[0 0 600 700],'Visible','off', 'CloseRequestFcn',@app.DoNothing));                        
                        movegui(app.summaryFigs(end), 'northwest');
                    end
                end
            end
            % Show figures:
            arrayfun(@(x) set(x, 'Visible', 'on'), app.summaryFigs);
        end
        
        
        function [x,y,mD] = getData(app,groupID,obsID)
            % This function retrieves the data and  acquisition index
            % from the "stats_data" property of app.statsObj for all
            % recordings from a given group ("groupID") for a given
            % observation ("obsID").
            % Inputs:
            %   groupID (char): name of experimental group;
            %   obsID (char): name of observation.
            % Outputs:
            %   x (num. array): list of the acquisition indexes for
            %   each data point.
            %   y (num. array): data.
            %   mD(cell array): metaData of "data".
            
            disp('retrieving data from stats object...')
            idxG = strcmp(groupID,app.statsObj.stats_data(:,1));
            indxObs = cellfun(@(x) find(strcmp(obsID,x)), app.statsObj.stats_data(:,...
                strcmp(app.statsObj.stats_data_headers,'observationID')),'UniformOutput',false);
            mD = app.statsObj.stats_data(idxG & ~isempty(indxObs),ismember(app.statsObj.stats_data_headers,...
                {'groupID', 'SubjectID', 'AcquisitionID', 'ModalityID', 'RecStartDateTime', 'data', 'AcquisitionIndx'}));
            % Drop rows with missing observation:
            mD(cellfun(@isempty,indxObs),:) =  [];
            indxObs(cellfun(@isempty,indxObs)) =  [];
            % Get Acquisition index and data point:
            y = single.empty(0,size(mD,1));
            x=y;
            for k = 1:size(mD,1)
                x(k) = mD{k,end};
                y(k) = mD{k,end-1}{indxObs{k}};
            end
            % Drop last column of data:
            mD(:,end-1:end) = [];
            % Sort data and metadata using ascending order of the
            % acquisition indices:
            [x,idx] = sort(x);
            y = y(idx);
            mD = mD(idx,:);
            % Add ObsID to metaData:
            mD = horzcat(mD, repmat({obsID},size(mD,1),1));
            
        end


        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% CUSTOM CALLBACKS %%%%%%%%%%%%%%%%%%%%%%
        function DoNothing(app,~,~)
            % EMPTY!%
            
        end


        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% %%%%%%
        
      
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, statsObj)
            
            %
            app.UIFigure.Visible = 'off';
            movegui(app.UIFigure, 'southeast');
            app.UIFigure.Visible = 'on';
            app.statsObj = statsObj;
            % Populate dropdown list:
            app.ROIinmainfigureDropDown.Items = app.statsObj.obs_list;
            % Create main figure:
            app.mainFig = figure('Name', ['Main - ' app.ROIinmainfigureDropDown.Value], 'NumberTitle','off',...
                'Position', [0 0 650 500],'Visible', 'on', 'CloseRequestFcn', @app.DoNothing);
            app.mainAx = axes('Parent', app.mainFig, 'Position',[.1 .2 .8 .6], 'Units',"normalized");
            % Create summary figures:
            app.createSummaryFigs;
            % Show Figures and app:
            app.ShowPlotInMainFig;
            movegui(app.mainFig, 'northeast');
            app.mainFig.Visible = 'on';
            
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.mainFig)
            delete(app.summaryFigs)
            delete(app)
            
        end

        % Value changed function: ROIinmainfigureDropDown
        function ShowPlotInMainFig(app, event)
            % This callback makes a copy of selected ROI plot from the
            % summaryFigs into the main fig axis                        
            idx_plot = strcmp(app.ROIinmainfigureDropDown.Value, arrayfun(@(x) x.metaData{1,end},app.scatData,'UniformOutput',false));
            cla(app.mainAx);
            copyobj(app.scatData(idx_plot).handles(1).Parent.Children,app.mainAx);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.OutputMenu = uimenu(app.UIFigure);
            app.OutputMenu.Text = 'Output';
            app.SaveasMenu = uimenu(app.OutputMenu);
            app.SaveasMenu.Text = 'Save as...';
            app.ExportasPNGMenu = uimenu(app.OutputMenu);
            app.ExportasPNGMenu.Text = 'Export as.PNG';
            app.SettingsMenu = uimenu(app.UIFigure);
            app.SettingsMenu.Text = 'Settings';
            app.AxissettingsMenu = uimenu(app.SettingsMenu);
            app.AxissettingsMenu.Text = 'Axis settings';
            app.ColorMenu = uimenu(app.SettingsMenu);
            app.ColorMenu.Text = 'Color';
            app.ROIinmainfigureDropDownLabel = uilabel(app.UIFigure);
            app.ROIinmainfigureDropDownLabel.Text = 'ROI in main figure';
            app.ROIinmainfigureDropDown = uidropdown(app.UIFigure);
            app.ROIinmainfigureDropDown.ValueChangedFcn = createCallbackFcn(app, @ShowPlotInMainFig, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlotLongData(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end